classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TexttospeachappUIFigure       matlab.ui.Figure
        AdvancedTexttoSpeechConverterLabel_2  matlab.ui.control.Label
        YourTextEditFieldLabel        matlab.ui.control.Label
        YourTextEditField             matlab.ui.control.EditField
        AdvancedTexttoSpeechConverterLabel  matlab.ui.control.Label
        VoicetypeListBoxLabel         matlab.ui.control.Label
        VoicetypeListBox              matlab.ui.control.ListBox
        SpeedSliderLabel              matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SamplingrateKHzDropDownLabel  matlab.ui.control.Label
        SamplingrateKHzDropDown       matlab.ui.control.DropDown
        PlayButton                    matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
    end


    properties (Access = private)
     player 
     audio
     fs
    end


    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if strcmp(app.YourTextEditField,'')==1
                app.Label.Text='please, Write  something in the text area!!';
            else
                %turn off the warning
                app.Label.Text=' ';
                %select voice
                list=app.VoicetypeListBox.Value;
                if strcmp(list,'David')==1
                    VOICE='  Microsoft David Desktop - English (United States)';
                else 
                 VOICE='Microsoft Zira Desktop - English (United States)';
                end
              %selct the voice speed 
              SPEED=app.SpeedSlider.Value;
              %selecting simpling rate
              app.fs=str2num(app.SamplingrateKHzDropDown.Value);
              app.audio=project(char(app.YourTextEditField.Value),VOICE,SPEED,app.fs);
              app.player=audioplayer(app.audio,app.fs);
              play(app.player);
                
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %select the format and path
            [file,path]=uiputfile('TTS.ogg');
            audiowrite(strcat(path,file),app.audio,app.fs);
        
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TexttospeachappUIFigure = uifigure;
            app.TexttospeachappUIFigure.Name = 'Text to speach app ';
            app.AdvancedTexttoSpeechConverterLabel_2 = uilabel(app.TexttospeachappUIFigure);
            app.AdvancedTexttoSpeechConverterLabel_2.Text = 'Advanced Text to Speech Converter';
            app.YourTextEditFieldLabel = uilabel(app.TexttospeachappUIFigure);
            app.YourTextEditFieldLabel.Text = 'Your Text';
            app.YourTextEditField = uieditfield(app.TexttospeachappUIFigure, 'text');
            app.AdvancedTexttoSpeechConverterLabel = uilabel(app.TexttospeachappUIFigure);
            app.AdvancedTexttoSpeechConverterLabel.Text = 'Advanced Text to Speech Converter';
            app.VoicetypeListBoxLabel = uilabel(app.TexttospeachappUIFigure);
            app.VoicetypeListBoxLabel.Text = 'Voice type';
            app.VoicetypeListBox = uilistbox(app.TexttospeachappUIFigure);
            app.VoicetypeListBox.Items = {'David', 'zira'};
            app.VoicetypeListBox.Value = 'David';
            app.SpeedSliderLabel = uilabel(app.TexttospeachappUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.TexttospeachappUIFigure);
            app.SpeedSlider.Limits = [-10 10];
            app.SamplingrateKHzDropDownLabel = uilabel(app.TexttospeachappUIFigure);
            app.SamplingrateKHzDropDownLabel.Text = 'Sampling rate(KHz)';
            app.SamplingrateKHzDropDown = uidropdown(app.TexttospeachappUIFigure);
            app.SamplingrateKHzDropDown.Items = {'8000', '11025', '12000', '16000', '22050', '24000', '32000', '44100', '48000'};
            app.SamplingrateKHzDropDown.Value = '16000';
            app.PlayButton = uibutton(app.TexttospeachappUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.TexttospeachappUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.TexttospeachappUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.SaveButton = uibutton(app.TexttospeachappUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResumeButton = uibutton(app.TexttospeachappUIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.Label = uilabel(app.TexttospeachappUIFigure);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TexttospeachappUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TexttospeachappUIFigure)
        end
    end
end